classdef DASTgaze < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PlotPanel                       matlab.ui.container.Panel
        TrialSpinner                    matlab.ui.control.Spinner
        TrialSpinnerLabel               matlab.ui.control.Label
        ValidCheckBoxR                  matlab.ui.control.CheckBox
        ValidCheckBoxL                  matlab.ui.control.CheckBox
        UIAxesRight                     matlab.ui.control.UIAxes
        UIAxesRight_2                   matlab.ui.control.UIAxes
        UIAxesLeft_2                    matlab.ui.control.UIAxes
        UIAxesLeft                      matlab.ui.control.UIAxes
        EyeDataPanel                    matlab.ui.container.Panel
        LengthsEditField                matlab.ui.control.EditField
        LengthsEditFieldLabel           matlab.ui.control.Label
        NoisebeforetargetsEditField     matlab.ui.control.EditField
        NoisebeforetargetsEditFieldLabel  matlab.ui.control.Label
        SilencebeforenoisesEditField    matlab.ui.control.EditField
        SilencebeforenoisesEditFieldLabel  matlab.ui.control.Label
        DiscardifdataismissingEditField  matlab.ui.control.EditField
        DiscardifdataismissingEditFieldLabel  matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        PreprocessingSettingsPanel      matlab.ui.container.Panel
        LowPassWindowsEditField         matlab.ui.control.EditField
        LowPassWindowsEditFieldLabel    matlab.ui.control.Label
        MinimumgaplengthforextensionEditField  matlab.ui.control.EditField
        MinimumgaplengthforextensionEditFieldLabel  matlab.ui.control.Label
        GapExtension                    matlab.ui.control.EditField
        GapExtensionLabel               matlab.ui.control.Label
        GazeDataSamplingFrequencyHzEditField  matlab.ui.control.EditField
        GazeDataSamplingFrequencyHzEditFieldLabel  matlab.ui.control.Label
        CheckBox                        matlab.ui.control.CheckBox
        DifferenceThresholdEditField    matlab.ui.control.EditField
        DifferenceThresholdEditFieldLabel  matlab.ui.control.Label
        RangeEditField                  matlab.ui.control.EditField
        RangeEditFieldLabel             matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        CheckIfGazeDataispresentButton  matlab.ui.control.Button
        UploadedDataEditField           matlab.ui.control.EditField
        LoadPresetButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        plotData    % Description
        Param       %settings parameters
        check       %used to check if variables have been passed from th emain app
    end
    
    methods (Access = private)
        
        function CheckFieldNames(app)
            
            s = app.plotData.GazeData.GazeStruct(1).SubGazeStruct;

            fields = fieldnames(s);
            left_single_fields = {};
            
            for i = 1:numel(fields)
                if startsWith(fields{i}, 'left') && isa(s.(fields{i}), 'single')
                    left_single_fields{end+1} = fields{i}; %#ok<AGROW>
                end
            end
            
            disp(left_single_fields);
        end
        
        function [left,right] = ExtractEyeData(app,data)

            N = numel(data.GazeStruct);             % number of top-level structs
            lens = zeros(N,1);
            
            for i = 1:N
                % assume SubGazeStruct is a struct array as well
                % and we’re only interested in the first element? or all?
                lens(i) = length(data.GazeStruct(i).SubGazeStruct.left_pupil_diameter);
            end
            
            Left = nan(numel(data.GazeStruct),max(lens));
            Right = nan(numel(data.GazeStruct),max(lens));
            
            for i = 1:N
                data_L = data.GazeStruct(i).SubGazeStruct.left_pupil_diameter;
                data_R = data.GazeStruct(i).SubGazeStruct.right_pupil_diameter;
            
                left(i,1:length(data_L)) = data_L;
                right(i,1:length(data_R)) = data_R;
            end
                
            clear data_R data_L lens N i 

        end                
        
        function plotPupilData(app)
                num = app.TrialSpinner.Value;
                
                app.ValidCheckBoxL.Value = app.plotData.Val_L_logic(num);
                app.ValidCheckBoxR.Value = app.plotData.Val_R_logic(num);


                app.Param.BaselineSamples = (app.Param.DurationSilence + app.Param.DurationNoise)*app.Param.Fs;
                app.Param.EndSample = (app.Param.DurationSilence + app.Param.DurationNoise + app.Param.endTime)*app.Param.Fs;


                % Left
                cla(app.UIAxesLeft)
                plot(app.UIAxesLeft, app.plotData.t_B, app.plotData.Cleaned_Diameter_L_B(num, app.Param.BaselineSamples:app.Param.EndSample)); 
                hold(app.UIAxesLeft, "on")
                plot(app.UIAxesLeft, app.plotData.t_B, app.plotData.LP_Diameter_L_B(num, app.Param.BaselineSamples:app.Param.EndSample), LineWidth=2);
                legend(app.UIAxesLeft, 'Cleaned', 'Low Pass');
                %grid(app.UIAxesLeft, 'on');
        
                % Right (same pattern)
                cla(app.UIAxesRight)
                plot(app.UIAxesRight, app.plotData.t_B, app.plotData.Cleaned_Diameter_R_B(num, app.Param.BaselineSamples:app.Param.EndSample));
                hold(app.UIAxesRight, "on")
                plot(app.UIAxesRight, app.plotData.t_B, app.plotData.LP_Diameter_R_B(num, app.Param.BaselineSamples:app.Param.EndSample), LineWidth=2);
                legend(app.UIAxesRight, 'Cleaned', 'Low Pass');
                %grid(app.UIAxesRight, 'on');
        end


        function checkvalidity(app)
            
            app.Param.BaselineSamples = (app.Param.DurationSilence + app.Param.DurationNoise)*app.Param.Fs;
            app.Param.EndSample = (app.Param.DurationSilence + app.Param.DurationNoise + app.Param.endTime)*app.Param.Fs;

            tot = size(app.plotData.Cleaned_Diameter_L_B(:,app.Param.BaselineSamples:app.Param.EndSample),2);
            
            Val_R = sum(isnan(app.plotData.Cleaned_Diameter_R_B(:,app.Param.BaselineSamples:app.Param.EndSample)),2);
            Val_L = sum(isnan(app.plotData.Cleaned_Diameter_L_B(:,app.Param.BaselineSamples:app.Param.EndSample)),2);
            
            %check validity of the data
            app.plotData.Val_R_logic = Val_R./tot < app.Param.DelThresh;
            app.plotData.Val_L_logic = Val_L./tot < app.Param.DelThresh;

        end


        function updatePlot(app)
            
            cla(app.UIAxesLeft);
            cla(app.UIAxesRight);

            %cla(app.UIAxesLeft_2, "reset");
            objsToDelete = findall(app.UIAxesLeft_2, 'Type', 'line'); % linee
            patchesToDelete = findall(app.UIAxesLeft_2, 'Type', 'patch'); % fill / patch
            delete([objsToDelete; patchesToDelete]);

            objsToDelete = findall(app.UIAxesRight_2, 'Type', 'line'); % linee
            patchesToDelete = findall(app.UIAxesRight_2, 'Type', 'patch'); % fill / patch
            delete([objsToDelete; patchesToDelete]);


            % plot
            plotPupilData(app);

            plotmeansem(app.plotData.t_B,app.plotData.LP_Diameter_L_B(app.plotData.Val_L_logic,app.Param.BaselineSamples:app.Param.EndSample)',[0 0 1],app.UIAxesLeft_2)
            plotmeansem(app.plotData.t_B,app.plotData.LP_Diameter_R_B(app.plotData.Val_R_logic,app.Param.BaselineSamples:app.Param.EndSample)',[1 0 0 ],app.UIAxesRight_2)


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, path)
            app.check.path = false;

           
            if exist("path", "var")
                app.UploadedDataEditField.Value = path;  
                app.check.path = true;
            end


        end

        % Button pushed function: LoadPresetButton
        function LoadPresetButtonPushed(app, event)
            
            dirname = append(pwd, '\results');
            [filename,pathname] = uigetfile(fullfile(dirname,'*.mat'));
            if filename==0
                % user pressed cancel
                drawnow;
                figure(app.UIFigure);
                return
            else
                % loading variable
                fullfilename = fullfile(pathname,filename);
                load(fullfilename,'data');
                app.plotData.GazeData = data;

                [~,f,~]=fileparts(fullfilename);
                filename=fullfile(f);
                app.UploadedDataEditField.Value = filename;
                drawnow;
                figure(app.UIFigure);

            end

            % Get focus back to the main figure
            focus(app.UIFigure);
        end

        % Button pushed function: CheckIfGazeDataispresentButton
        function CheckIfGazeDataispresentButtonPushed(app, event)
            if isfield(app.plotData.GazeData, 'GazeStruct')
                app.Lamp.Color = [0 1 0];
                app.PreprocessingSettingsPanel.Visible = "on";
                app.EyeDataPanel.Visible = "on";
            else 
                app.Lamp.Color = [1 0 0];
            end

            app.SilencebeforenoisesEditField.Value = string(app.plotData.GazeData.Settings.DurationSilenceBefore);
            app.NoisebeforetargetsEditField.Value = string(app.plotData.GazeData.Settings.DurationBefore);
            %app.NoisebeforetargetsEditField.Value = string(app.plotData.GazeData.Settings.DurationBefore);


        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            addpath functions\


            %Cleaning 
            app.Param.Range = str2double(split(erase(app.RangeEditField.Value, ["[", "]"]))).'; % e.g. [mm] or quantile: Set values outside this range to NaN
            app.Param.RangeQuantile = 0;
            app.Param.DiffThresh = str2double(app.DifferenceThresholdEditField.Value); % [mm] or [Pixels]: If the difference from one sample to the next sample exceeds this thereshold, both samples will be set to NaN.
            if app.CheckBox.Value == true
                app.Param.DiffQuantile = 1;
            else
                app.Param.DiffQuantile = 0;
            end
            
            % Preprocessing
            app.Param.Fs = str2double(app.GazeDataSamplingFrequencyHzEditField.Value); % [Hz]: Sampling rate of pupil data
            app.Param.RemoveBeforeAndAfter = str2double(split(erase(app.GapExtension.Value, ["[", "]"]))).'*1e-3; % [s]: Samples within the time ramge before and after NaNs will set NaNs as well
            app.Param.MinLengthNanRepair = str2double(app.MinimumgaplengthforextensionEditField.Value); % [samples]: Drop values (i.e., change to NaN) before and after NaNs only for contiguous NaNs of at least __ samples.
            app.Param.LPWinSize = str2double(app.LowPassWindowsEditField.Value); % [s]: Window size of hamming-window for low-pass filtering

            app.Param.DelThresh = str2double(app.DiscardifdataismissingEditField.Value)*0.01;
            app.Param.endTime = str2double(app.LengthsEditField.Value);

            
            app.Param.DurationSilence = str2double(app.SilencebeforenoisesEditField.Value);
            app.Param.DurationNoise = str2double(app.NoisebeforetargetsEditField.Value);

            app.PlotPanel.Visible = 'on';

            % Prepare low-pass window
            app.plotData.LPWindow = hamming(round(app.Param.LPWinSize*app.Param.Fs));
            app.plotData.LPWindow = app.plotData.LPWindow/sum(app.plotData.LPWindow);


            [app.plotData.LeftEye,app.plotData.RightEye] = app.ExtractEyeData(app.plotData.GazeData);     

            
            app.plotData.Ntrials = size(app.plotData.LeftEye,1);

            app.TrialSpinner.Limits = [1 app.plotData.Ntrials];

            app.Param.BaselineSamples = (app.Param.DurationSilence + app.Param.DurationNoise)*app.Param.Fs + 1;
            app.Param.EndSample = (app.Param.DurationSilence + app.Param.DurationNoise + app.Param.endTime)*app.Param.Fs + 1;

            % run all pupil processing
            app.plotData = processPupilData(app.plotData, app.Param); 
            app.checkvalidity();

            app.plotData.t_B = app.plotData.t_B - (app.Param.DurationSilence + app.Param.DurationNoise);


        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            updatePlot(app)

        end

        % Value changed function: ValidCheckBoxR
        function ValidCheckBoxRValueChanged(app, event)
            value = app.ValidCheckBoxR.Value;
            num = app.TrialSpinner.Value;
            app.plotData.Val_R_logic(num) = value;
            updatePlot(app)
        end

        % Value changed function: ValidCheckBoxL
        function ValidCheckBoxLValueChanged(app, event)
            value = app.ValidCheckBoxL.Value;
            num = app.TrialSpinner.Value;
            app.plotData.Val_L_logic(num) = value;
            updatePlot(app)
        end

        % Value changed function: TrialSpinner
        function TrialSpinnerValueChanged(app, event)
            num = app.TrialSpinner.Value;
            app.ValidCheckBoxL.Value = app.plotData.Val_L_logic(num);
            app.ValidCheckBoxR.Value = app.plotData.Val_R_logic(num);

            updatePlot(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadPresetButton = uibutton(app.UIFigure, 'push');
            app.LoadPresetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPresetButtonPushed, true);
            app.LoadPresetButton.Text = 'Load eye data';
            app.UploadedDataEditField = uieditfield(app.UIFigure, 'text');
            app.UploadedDataEditField.Editable = 'off';
            app.CheckIfGazeDataispresentButton = uibutton(app.UIFigure, 'push');
            app.CheckIfGazeDataispresentButton.ButtonPushedFcn = createCallbackFcn(app, @CheckIfGazeDataispresentButtonPushed, true);
            app.CheckIfGazeDataispresentButton.Text = 'Check If Gaze Data is present';
            app.Lamp = uilamp(app.UIFigure);
            app.PreprocessingSettingsPanel = uipanel(app.UIFigure);
            app.PreprocessingSettingsPanel.Title = 'Preprocessing Settings';
            app.PreprocessingSettingsPanel.Visible = 'off';
            app.RangeEditFieldLabel = uilabel(app.PreprocessingSettingsPanel);
            app.RangeEditFieldLabel.Text = 'Range ';
            app.RangeEditField = uieditfield(app.PreprocessingSettingsPanel, 'text');
            app.RangeEditField.Tooltip = {' [mm] Set values outside this range to NaN'};
            app.RangeEditField.Value = '[1 8]';
            app.DifferenceThresholdEditFieldLabel = uilabel(app.PreprocessingSettingsPanel);
            app.DifferenceThresholdEditFieldLabel.Text = 'Difference Threshold';
            app.DifferenceThresholdEditField = uieditfield(app.PreprocessingSettingsPanel, 'text');
            app.DifferenceThresholdEditField.Tooltip = {' [mm] or quantile: If the difference from one sample to the next sample exceeds this thereshold, both samples will be set to NaN.'};
            app.DifferenceThresholdEditField.Value = '1';
            app.CheckBox = uicheckbox(app.PreprocessingSettingsPanel);
            app.CheckBox.Tooltip = {'% If marked, Difference Threshold is interpreted as quantile and should be between 0 and 1.'};
            app.CheckBox.Text = '%';
            app.GazeDataSamplingFrequencyHzEditFieldLabel = uilabel(app.PreprocessingSettingsPanel);
            app.GazeDataSamplingFrequencyHzEditFieldLabel.Text = 'Gaze Data Sampling Frequency [Hz] ';
            app.GazeDataSamplingFrequencyHzEditField = uieditfield(app.PreprocessingSettingsPanel, 'text');
            app.GazeDataSamplingFrequencyHzEditField.Tooltip = {' Sampling rate of pupil data'};
            app.GazeDataSamplingFrequencyHzEditField.Value = '60';
            app.GapExtensionLabel = uilabel(app.PreprocessingSettingsPanel);
            app.GapExtensionLabel.Text = 'Gap Extension';
            app.GapExtension = uieditfield(app.PreprocessingSettingsPanel, 'text');
            app.GapExtension.Tooltip = {'Samples within the time ramge before and after NaNs will set NaNs as well'};
            app.GapExtension.Value = '[35 100]';
            app.MinimumgaplengthforextensionEditFieldLabel = uilabel(app.PreprocessingSettingsPanel);
            app.MinimumgaplengthforextensionEditFieldLabel.Text = 'Minimum gap length for extension';
            app.MinimumgaplengthforextensionEditField = uieditfield(app.PreprocessingSettingsPanel, 'text');
            app.MinimumgaplengthforextensionEditField.Tooltip = {'Drop values (i.e., change to NaN) before and after NaNs only for contiguous NaNs of at least __ samples.'};
            app.MinimumgaplengthforextensionEditField.Value = '10';
            app.LowPassWindowsEditFieldLabel = uilabel(app.PreprocessingSettingsPanel);
            app.LowPassWindowsEditFieldLabel.Text = 'Low Pass  Window [s]';
            app.LowPassWindowsEditField = uieditfield(app.PreprocessingSettingsPanel, 'text');
            app.LowPassWindowsEditField.Tooltip = {' Window size of hamming-window for low-pass filtering'};
            app.LowPassWindowsEditField.Value = '.1';
            app.EyeDataPanel = uipanel(app.UIFigure);
            app.EyeDataPanel.Title = 'Eye Data';
            app.EyeDataPanel.Visible = 'off';
            app.PlotButton = uibutton(app.EyeDataPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SaveButton = uibutton(app.EyeDataPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DiscardifdataismissingEditFieldLabel = uilabel(app.EyeDataPanel);
            app.DiscardifdataismissingEditFieldLabel.Text = 'Discard if % data is missing';
            app.DiscardifdataismissingEditField = uieditfield(app.EyeDataPanel, 'text');
            app.DiscardifdataismissingEditField.Tooltip = {'Samples within the time ramge before and after NaNs will set NaNs as well'};
            app.DiscardifdataismissingEditField.Value = '30';
            app.SilencebeforenoisesEditFieldLabel = uilabel(app.EyeDataPanel);
            app.SilencebeforenoisesEditFieldLabel.Text = 'Silence before noise  [s]';
            app.SilencebeforenoisesEditField = uieditfield(app.EyeDataPanel, 'text');
            app.SilencebeforenoisesEditField.Tooltip = {'Samples within the time ramge before and after NaNs will set NaNs as well'};
            app.NoisebeforetargetsEditFieldLabel = uilabel(app.EyeDataPanel);
            app.NoisebeforetargetsEditFieldLabel.Text = 'Noise before target [s]';
            app.NoisebeforetargetsEditField = uieditfield(app.EyeDataPanel, 'text');
            app.NoisebeforetargetsEditField.Tooltip = {'Samples within the time ramge before and after NaNs will set NaNs as well'};
            app.LengthsEditFieldLabel = uilabel(app.EyeDataPanel);
            app.LengthsEditFieldLabel.Text = 'Length [s]';
            app.LengthsEditField = uieditfield(app.EyeDataPanel, 'text');
            app.LengthsEditField.Tooltip = {'Time window in seconds counted after the sentence onset'};
            app.LengthsEditField.Value = '3';
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Visible = 'off';
            app.UIAxesLeft = uiaxes(app.PlotPanel);
            title(app.UIAxesLeft, 'Left Eye')
            xlabel(app.UIAxesLeft, 'Time [s]')
            ylabel(app.UIAxesLeft, 'Relative Pupil Diameter [mm]')
            zlabel(app.UIAxesLeft, 'Z')
            app.UIAxesLeft_2 = uiaxes(app.PlotPanel);
            title(app.UIAxesLeft_2, 'Left Eye Grand Average ')
            xlabel(app.UIAxesLeft_2, 'Time [s]')
            ylabel(app.UIAxesLeft_2, 'Relative Pupil Diameter [mm]')
            zlabel(app.UIAxesLeft_2, 'Z')
            app.UIAxesRight_2 = uiaxes(app.PlotPanel);
            title(app.UIAxesRight_2, 'Right Eye Grand Average ')
            xlabel(app.UIAxesRight_2, 'Time [s]')
            ylabel(app.UIAxesRight_2, 'Relative Pupil Diameter [mm]')
            zlabel(app.UIAxesRight_2, 'Z')
            app.UIAxesRight = uiaxes(app.PlotPanel);
            title(app.UIAxesRight, 'RightEye')
            xlabel(app.UIAxesRight, 'Time [s]')
            ylabel(app.UIAxesRight, 'Relative Pupil Diameter [mm]')
            zlabel(app.UIAxesRight, 'Z')
            app.ValidCheckBoxL = uicheckbox(app.PlotPanel);
            app.ValidCheckBoxL.ValueChangedFcn = createCallbackFcn(app, @ValidCheckBoxLValueChanged, true);
            app.ValidCheckBoxL.Text = 'Valid';
            app.ValidCheckBoxR = uicheckbox(app.PlotPanel);
            app.ValidCheckBoxR.ValueChangedFcn = createCallbackFcn(app, @ValidCheckBoxRValueChanged, true);
            app.ValidCheckBoxR.Text = 'Valid';
            app.TrialSpinnerLabel = uilabel(app.PlotPanel);
            app.TrialSpinnerLabel.Text = 'Trial';
            app.TrialSpinner = uispinner(app.PlotPanel);
            app.TrialSpinner.ValueChangedFcn = createCallbackFcn(app, @TrialSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DASTgaze(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end